classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Gauge                  matlab.ui.control.SemicircularGauge
        GaugeLabel             matlab.ui.control.Label
        ADCTextEditField       matlab.ui.control.EditField
        ADCTextEditFieldLabel  matlab.ui.control.Label
        ConnectButton          matlab.ui.control.Button
        DisconnectButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        SerialObj % Đối tượng quản lý cổng COM
    end
    
    methods (Access = private)
        
        function ReadSerialData(app, ~)
    try
        % Đọc 1 dòng dữ liệu từ bộ đệm (đã loại bỏ sẵn \r\n do cấu hình terminator)
        rawData = readline(app.SerialObj);
        
        % Hàm sscanf sẽ tự động tìm 2 giá trị số dựa trên định dạng chuỗi
        parsedData = sscanf(rawData, 'ADC:%f,V:%f');
        
        % Kiểm tra xem có tách thành công đúng 2 giá trị hay không (để chống nhiễu rác)
        if numel(parsedData) == 2
            adc_raw = parsedData(1);
            voltage = parsedData(2);
            
            % Đẩy dữ liệu ra UI (Cập nhật giao diện)
            app.ADCText.Value = num2str(adc_raw);
            app.VoltageGauge.Value = voltage;
        end
    catch
        % Nếu có chuỗi rác do Proteus vừa khởi động, bỏ qua không làm crash App
    end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

    try
        % 1. Khởi tạo cổng COM2, Baudrate 9600
        app.SerialObj = serialport("COM2", 9600);
        
        % 2. Định nghĩa ký tự kết thúc chuỗi là \r\n (CR/LF)
        configureTerminator(app.SerialObj, "CR/LF");
        
        % 3. Kích hoạt Callback: Cứ nhận đủ 1 dòng (terminator) thì gọi hàm ReadSerialData
        configureCallback(app.SerialObj, "terminator", @app.ReadSerialData);
        
        % 4. Cập nhật trạng thái nút bấm
        app.ConnectButton.Enable = 'off';
        app.DisconnectButton.Enable = 'on';
        
    catch ME
        % Hiển thị thông báo lỗi nếu COM2 chưa mở hoặc bị chiếm dụng
        uialert(app.UIFigure, ME.message, 'Lỗi kết nối COM2');
    end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)

    try
        % 1. Tắt chế độ ngắt đọc dữ liệu
        configureCallback(app.SerialObj, "off");
        
        % 2. Đóng cổng và xóa đối tượng
        clear app.SerialObj;
        
        % 3. Cập nhật lại giao diện nút bấm
        app.ConnectButton.Enable = 'on';
        app.DisconnectButton.Enable = 'off';
        
        % 4. Reset hiển thị
        app.ADCText.Value = '';
        app.VoltageGauge.Value = 0;
    catch
        uialert(app.UIFigure, 'Không thể ngắt kết nối!', 'Lỗi');
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'DisconnectButton';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'ConnectButton';
            app.ADCTextEditFieldLabel = uilabel(app.UIFigure);
            app.ADCTextEditFieldLabel.Text = 'ADCText ';
            app.ADCTextEditField = uieditfield(app.UIFigure, 'text');
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end